classdef DensoSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Joint_1SliderLabel  matlab.ui.control.Label
        Joint_1Slider       matlab.ui.control.Slider
        Joint_2SliderLabel  matlab.ui.control.Label
        Joint_2Slider       matlab.ui.control.Slider
        Joint_3SliderLabel  matlab.ui.control.Label
        Joint_3Slider       matlab.ui.control.Slider
        Joint_4SliderLabel  matlab.ui.control.Label
        Joint_4Slider       matlab.ui.control.Slider
        Joint_5SliderLabel  matlab.ui.control.Label
        Joint_5Slider       matlab.ui.control.Slider
        Joint_6SliderLabel  matlab.ui.control.Label
        Joint_6Slider       matlab.ui.control.Slider
        q1EditFieldLabel    matlab.ui.control.Label
        q1EditField         matlab.ui.control.EditField
        q2EditFieldLabel    matlab.ui.control.Label
        q2EditField         matlab.ui.control.EditField
        q3EditFieldLabel    matlab.ui.control.Label
        q3EditField         matlab.ui.control.EditField
        q4EditFieldLabel    matlab.ui.control.Label
        q4EditField         matlab.ui.control.EditField
        q5EditFieldLabel    matlab.ui.control.Label
        q5EditField         matlab.ui.control.EditField
        q6EditFieldLabel    matlab.ui.control.Label
        q6EditField         matlab.ui.control.EditField
        DensoVS060Label     matlab.ui.control.Label
    end

    
    properties (Access = public)
        denso = DensoVS060(false,transl(0,0,0),'DensoVS060'); % Description
    end
    
    methods (Access = public)
        
        function qJoints = GetJointState(app)
            q1 = deg2rad(app.Joint_1Slider.Value);
            q2 = deg2rad(app.Joint_2Slider.Value);
            q3 = deg2rad(app.Joint_3Slider.Value);
            q4 = deg2rad(app.Joint_4Slider.Value);
            q5 = deg2rad(app.Joint_5Slider.Value);
            q6 = deg2rad(app.Joint_6Slider.Value);
            qJoints = [q1,q2,q3,q4,q5,q6];
            assignin('base',"q",qJoints);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: q1EditField, q1EditField
        function q1EditFieldValueChanged(app, event)
            app.Joint_1Slider.Value = str2double(app.q1EditField.Value);
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
        end

        % Callback function
        function Joint_1SliderValueChanged(app, event)
            app.q1EditField.Value = num2str(round(app.Joint_1Slider.Value,0));
        end

        % Callback function
        function Joint_2SliderValueChanged(app, event)
            app.q2EditField.Value = num2str(round(app.Joint_2Slider.Value,0));
        end

        % Value changed function: q2EditField
        function q2EditFieldValueChanged(app, event)
            app.Joint_2Slider.Value = str2double(app.q2EditField.Value);
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
        end

        % Callback function
        function Joint_3SliderValueChanged(app, event)
            app.q3EditField.Value = num2str(round(app.Joint_3Slider.Value,0)); 
        end

        % Callback function
        function Joint_4SliderValueChanged(app, event)
            app.q4EditField.Value = num2str(round(app.Joint_4Slider.Value,0));
        end

        % Callback function
        function Joint_5SliderValueChanged(app, event)
            app.q5EditField.Value = num2str(round(app.Joint_5Slider.Value,0));
        end

        % Callback function
        function Joint_6SliderValueChanged(app, event)
            app.q6EditField.Value = num2str(round(app.Joint_6Slider.Value,0));    
        end

        % Value changed function: q3EditField
        function q3EditFieldValueChanged(app, event)
            app.Joint_3Slider.Value = str2double(app.q3EditField.Value);
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
        end

        % Value changed function: q4EditField
        function q4EditFieldValueChanged(app, event)
            app.Joint_4Slider.Value = str2double(app.q4EditField.Value);
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
        end

        % Value changed function: q5EditField
        function q5EditFieldValueChanged(app, event)
            app.Joint_5Slider.Value = str2double(app.q5EditField.Value);
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
        end

        % Value changed function: q6EditField
        function q6EditFieldValueChanged(app, event)
            app.Joint_6Slider.Value = str2double(app.q6EditField.Value);
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
        end

        % Callback function
        function UIAxesButtonDown(app, event)
%             robot = DensoVS060(false,transl(0,0,0),'DensoVS060');
%             q = app.GetJointState;
%             robot.model.animate(q);
        end

        % Callback function
        function ApplyButtonPushed(app, event)
            q = app.GetJointState;
            assignin('base','q',q);
        end

        % Value changing function: Joint_1Slider
        function Joint_1SliderValueChanging(app, event)
            app.q1EditField.Value = num2str(round(event.Value,1));
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
        end

        % Value changing function: Joint_2Slider
        function Joint_2SliderValueChanging(app, event)
            app.q2EditField.Value = num2str(round(event.Value,1));
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
        end

        % Value changing function: Joint_3Slider
        function Joint_3SliderValueChanging(app, event)
            app.q3EditField.Value = num2str(round(event.Value,1));
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
        end

        % Value changing function: Joint_4Slider
        function Joint_4SliderValueChanging(app, event)
            app.q4EditField.Value = num2str(round(event.Value,1));
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
        end

        % Value changing function: Joint_5Slider
        function Joint_5SliderValueChanging(app, event)
            app.q5EditField.Value = num2str(round(event.Value,1));
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
        end

        % Value changing function: Joint_6Slider
        function Joint_6SliderValueChanging(app, event)
            app.q6EditField.Value = num2str(round(event.Value,1));
            q1 = deg2rad(str2double(app.q1EditField.Value));
            q2 = deg2rad(str2double(app.q2EditField.Value));
            q3 = deg2rad(str2double(app.q3EditField.Value));
            q4 = deg2rad(str2double(app.q4EditField.Value));
            q5 = deg2rad(str2double(app.q5EditField.Value));
            q6 = deg2rad(str2double(app.q6EditField.Value));
            q = [q1,q2,q3,q4,q5,q6];
            app.denso.model.animate(q);
        end

        % Callback function
        function LoadRobotPushed(app, event)
            robot = DensoVS060(false,transl(0,0,0),'robot');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Joint_1SliderLabel = uilabel(app.UIFigure);
            app.Joint_1SliderLabel.Text = 'Joint_1';
            app.Joint_1Slider = uislider(app.UIFigure);
            app.Joint_1Slider.Limits = [-170 170];
            app.Joint_1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint_1SliderValueChanging, true);
            app.Joint_2SliderLabel = uilabel(app.UIFigure);
            app.Joint_2SliderLabel.Text = 'Joint_2';
            app.Joint_2Slider = uislider(app.UIFigure);
            app.Joint_2Slider.Limits = [-120 120];
            app.Joint_2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint_2SliderValueChanging, true);
            app.Joint_3SliderLabel = uilabel(app.UIFigure);
            app.Joint_3SliderLabel.Text = 'Joint_3';
            app.Joint_3Slider = uislider(app.UIFigure);
            app.Joint_3Slider.Limits = [-125 155];
            app.Joint_3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint_3SliderValueChanging, true);
            app.Joint_4SliderLabel = uilabel(app.UIFigure);
            app.Joint_4SliderLabel.Text = 'Joint_4';
            app.Joint_4Slider = uislider(app.UIFigure);
            app.Joint_4Slider.Limits = [-270 270];
            app.Joint_4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint_4SliderValueChanging, true);
            app.Joint_5SliderLabel = uilabel(app.UIFigure);
            app.Joint_5SliderLabel.Text = 'Joint_5';
            app.Joint_5Slider = uislider(app.UIFigure);
            app.Joint_5Slider.Limits = [-120 120];
            app.Joint_5Slider.ValueChangingFcn = createCallbackFcn(app, @Joint_5SliderValueChanging, true);
            app.Joint_6SliderLabel = uilabel(app.UIFigure);
            app.Joint_6SliderLabel.Text = 'Joint_6';
            app.Joint_6Slider = uislider(app.UIFigure);
            app.Joint_6Slider.Limits = [-360 360];
            app.Joint_6Slider.ValueChangingFcn = createCallbackFcn(app, @Joint_6SliderValueChanging, true);
            app.q1EditFieldLabel = uilabel(app.UIFigure);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1EditField = uieditfield(app.UIFigure, 'text');
            app.q1EditField.ValueChangedFcn = createCallbackFcn(app, @q1EditFieldValueChanged, true);
            app.q1EditField.ValueChangingFcn = createCallbackFcn(app, @q1EditFieldValueChanged, true);
            app.q1EditField.Value = '0';
            app.q2EditFieldLabel = uilabel(app.UIFigure);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2EditField = uieditfield(app.UIFigure, 'text');
            app.q2EditField.ValueChangedFcn = createCallbackFcn(app, @q2EditFieldValueChanged, true);
            app.q2EditField.Value = '0';
            app.q3EditFieldLabel = uilabel(app.UIFigure);
            app.q3EditFieldLabel.Text = 'q3';
            app.q3EditField = uieditfield(app.UIFigure, 'text');
            app.q3EditField.ValueChangedFcn = createCallbackFcn(app, @q3EditFieldValueChanged, true);
            app.q3EditField.Value = '0';
            app.q4EditFieldLabel = uilabel(app.UIFigure);
            app.q4EditFieldLabel.Text = 'q4';
            app.q4EditField = uieditfield(app.UIFigure, 'text');
            app.q4EditField.ValueChangedFcn = createCallbackFcn(app, @q4EditFieldValueChanged, true);
            app.q4EditField.Value = '0';
            app.q5EditFieldLabel = uilabel(app.UIFigure);
            app.q5EditFieldLabel.Text = 'q5';
            app.q5EditField = uieditfield(app.UIFigure, 'text');
            app.q5EditField.ValueChangedFcn = createCallbackFcn(app, @q5EditFieldValueChanged, true);
            app.q5EditField.Value = '0';
            app.q6EditFieldLabel = uilabel(app.UIFigure);
            app.q6EditFieldLabel.Text = {'q6'; ''};
            app.q6EditField = uieditfield(app.UIFigure, 'text');
            app.q6EditField.ValueChangedFcn = createCallbackFcn(app, @q6EditFieldValueChanged, true);
            app.q6EditField.Value = '0';
            app.DensoVS060Label = uilabel(app.UIFigure);
            app.DensoVS060Label.Text = 'DensoVS060';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DensoSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end